classdef TSP_Manual_Solver_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSPManualOrderingAppUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ConnectUponOrderingCheckBox   matlab.ui.control.CheckBox
        ResetOrderingButton           matlab.ui.control.Button
        SaveTSPButton                 matlab.ui.control.Button
        GeneratePointsButton          matlab.ui.control.Button
        SameRangeforXYCheckBox        matlab.ui.control.CheckBox
        YmaxEditField                 matlab.ui.control.NumericEditField
        YmaxEditFieldLabel            matlab.ui.control.Label
        YminEditField                 matlab.ui.control.NumericEditField
        YminEditFieldLabel            matlab.ui.control.Label
        XmaxEditField                 matlab.ui.control.NumericEditField
        XmaxEditFieldLabel            matlab.ui.control.Label
        XminEditField                 matlab.ui.control.NumericEditField
        XminEditFieldLabel            matlab.ui.control.Label
        SizeEditField                 matlab.ui.control.NumericEditField
        SizeEditFieldLabel            matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        StartWithMenuItem             matlab.ui.container.Menu
        SetAsNextMenuItem             matlab.ui.container.Menu
        ResetBeforeMenuItem           matlab.ui.container.Menu
        ResetAfterMenuItem            matlab.ui.container.Menu
        ResetOrderMenuItem            matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % Columns: X, Y, Order
        pointsWithOrder (:, 3) = [];   
        
        % Flags used to decide whether to enable / disable 
        % GeneratePointsButton.
        xRangeIsValid (1, 1) logical = 1;
        yRangeIsValid (1, 1) logical = 1;

        % Flags telling if we have zero x and y ranges.
        xRangeIsZero (1, 1) logical = 0;
        yRangeIsZero (1, 1) logical = 0;

        % Points handles: They will be used to add context menu.
        pointsHandles = {};
        
        % Path hnadle: It will be used to keep track of the ordered TSP
        % path.
        pathHandle = handle([]);
        
        % It stores the index of the starting point of the ordered path.
        startIndex = NaN;
    end
    
    methods (Access = private)
        function cloneXRanageIntoYRange(app)
            % Setting Y range fields to same values as those of X
            app.YminEditField.Value = app.XminEditField.Value;
            app.YmaxEditField.Value = app.XmaxEditField.Value;

            % Trigger YRange changed.
            YRangeChanged(app);
        end

        function setEnablementOfYRangeFields(app, enableValue)
            % Enabling / disabling Y Range fields.
            app.YminEditField.Enable = enableValue;
            app.YmaxEditField.Enable = enableValue;
        end
        
        function assignGenerateButtonProperEnablement(app)
            % It will enable / disable GeneratePointsButton based on
            % app.enableGenerateButton flag with appropriate background
            % color.
            app.SizeEditField.BackgroundColor = "white";
            app.SizeEditField.Tooltip = "";
            app.GeneratePointsButton.Tooltip = "";

            if app.xRangeIsValid && app.yRangeIsValid
                app.GeneratePointsButton.Enable = "on";
                app.GeneratePointsButton.BackgroundColor = "white";

                if app.xRangeIsZero && app.yRangeIsZero && ...
                   (app.SizeEditField.Value > 1)              
                   % We should disable generation.  
                   app.GeneratePointsButton.Enable = "off";
                   app.SizeEditField.BackgroundColor = "red";
                   app.GeneratePointsButton.BackgroundColor = "red";

                   % Setting tooltip on size field to notify issue.
                   app.SizeEditField.Tooltip = "Due to zero ranges, size should be 1.";
                end
            else
                app.GeneratePointsButton.Enable = "off";
                app.GeneratePointsButton.BackgroundColor = "red";
            end

            if ~app.GeneratePointsButton.Enable
                app.GeneratePointsButton.Tooltip = "Check other red fields to identify errors.";
            end
        end
        
        function plotPoints(app)
            % Plots the generated points.
            cla(app.UIAxes);
            subtitle(app.UIAxes, "");

            numPoints = size(app.pointsWithOrder, 1);
            app.pointsHandles = cell(numPoints, 1);
            hold(app.UIAxes, "on");

            for i = 1 : numPoints
                h = plot(app.UIAxes, ...
                         app.pointsWithOrder(i, 1), ...
                         app.pointsWithOrder(i, 2), ...,
                         "ob", ...
                         "MarkerSize", 5, ...
                         "MarkerFaceColor", "b", ...
                         "LineWidth", 2, ...
                         "Tag", "P-" + num2str(i));

                % Adding Order in the data tip.
                h.DataTipTemplate.DataTipRows(end + 1).Label = "Order";
                h.DataTipTemplate.DataTipRows(end).Value = app.pointsWithOrder(i, end);

                % Adding context menu for point.
                h.ContextMenu = app.ContextMenu;

                app.pointsHandles{i} = h;
            end
        end
        
        function plotPath(app)
            % Plots path and updates points order.
            hold(app.UIAxes, "on");
            subtitle(app.UIAxes, "");
            numPoints = size(app.pointsWithOrder, 1);

            app.ResetOrderingButton.Enable = "off";
            app.SaveTSPButton.Enable = "off";

            if ~isempty(app.pathHandle)
                app.pathHandle.delete;
            end

            % Updating order labels.
            for i = 1:numPoints
                app.pointsHandles{i}.DataTipTemplate.DataTipRows(end).Value = app.pointsWithOrder(i, end);
            end

            if ~isnan(app.startIndex)
                app.pointsHandles{app.startIndex}.Marker = "o";
                app.pointsHandles{app.startIndex}.Color = "b";
                app.pointsHandles{app.startIndex}.MarkerFaceColor = "b";
            end

            app.startIndex = find(app.pointsWithOrder(:, end) == 1);

            if isempty(app.startIndex)
                app.startIndex = NaN;                

                % Nothing to do further.
                return;
            end

            % Enabling reset button.
            app.ResetOrderingButton.Enable = "on";

            app.pointsHandles{app.startIndex}.Marker = "*";
            app.pointsHandles{app.startIndex}.Color = "g";
            app.pointsHandles{app.startIndex}.MarkerFaceColor = "g";

            assignedIndices = find(app.pointsWithOrder(:, end) > 0);
            [~, sortingIndices] = sort(app.pointsWithOrder(assignedIndices, end));
            sortingIndices = assignedIndices(sortingIndices);
            
            % Computing total distance if path is fully ordered.
            totalDistance = NaN;
            if length(sortingIndices) == numPoints
                % Path is fully ordered. Note here that closed path is 
                % already assumed by calculateTotalDistance.
                totalDistance = calculateTotalDistance(app.pointsWithOrder(:, 1:2), ...
                                                       sortingIndices);

                % Adding first point at the end to close path. This is
                % needed to plot closed poth.
                sortingIndices(end + 1) = sortingIndices(1);
            end

            x = app.pointsWithOrder(sortingIndices, 1);
            y = app.pointsWithOrder(sortingIndices, 2);

            done = ~isnan(totalDistance);
            if done
                % TSP fully solved. We can save it now.
                app.SaveTSPButton.Enable = "on";
            end

            if app.ConnectUponOrderingCheckBox.Value || done
                app.pathHandle = plot(app.UIAxes, ...
                                      x, ...
                                      y, ...
                                      "-k", ...
                                      "LineWidth", 2, ...        
                                      "Tag", "Path");

                % Disabling data tips showing for path, as we have what is
                % needed among points handles.
                app.pathHandle.HitTest = "off";

                % Sending path plot to the back so points are on the 
                % front.
                uistack(app.pathHandle, "bottom");
            end

            % Adding distance as subtitle if fully ordered path.
            if ~isnan(totalDistance)
                subtitle(app.UIAxes, ...
                         "Total Distance : " + num2str(totalDistance));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setting axis to equal to maintain real perspective on
            % distances. This is important as we are visually solving TSP
            % problem, so the percieved distance should be visualized 
            % equally along all axes.
            axis(app.UIAxes, "equal");
            
            % Disabling default context menu. As we will add context menu
            % for each point.
            app.UIAxes.ContextMenu = [];
        end

        % Value changed function: SameRangeforXYCheckBox
        function SameRangeforXYCheckBoxValueChanged(app, event)
            % Monitors the check box for having Y Range following X Range
            % or not.
            matchXYRanges = app.SameRangeforXYCheckBox.Value;
            
            if matchXYRanges
                % Disable Y range fields.
                setEnablementOfYRangeFields(app, "off");

                % Setting Y range fields to same as those of X
                cloneXRanageIntoYRange(app);
            else
                % Match is not required. We only enable the Y range fields
                % without changing current values.
                setEnablementOfYRangeFields(app, "on");
            end

            % Calling value changed as this is not induced by external
            % user event to be automatically triggered.
            YRangeChanged(app);
        end

        % Value changed function: XmaxEditField, XminEditField
        function XRangeChanged(app, event)
            % Its main purpose is to check consistency in the range 
            % values.
            minX = app.XminEditField.Value;
            maxX = app.XmaxEditField.Value;

            if maxX < minX
                % Invalid x range.
                app.xRangeIsValid = 0;
                app.XminEditField.BackgroundColor = "red";
                app.XmaxEditField.BackgroundColor = "red";

                % Setting error message as tooltips.
                errorMsg = "Xmin should be <= Xmax.";
                app.XminEditField.Tooltip = errorMsg;
                app.XmaxEditField.Tooltip = errorMsg;
            else
                % Valid x range.
                app.xRangeIsValid = 1;
                app.XminEditField.BackgroundColor = "white";
                app.XmaxEditField.BackgroundColor = "white";
                
                % Resetting tooltips.
                app.XminEditField.Tooltip = "";
                app.XmaxEditField.Tooltip = "";

                deltaX = maxX - minX;

                % Dealing with zero delta.
                if deltaX == 0
                    app.xRangeIsZero = 1;
                    deltaX = 1;
                else
                    app.xRangeIsZero = 0;
                end

                xlim(app.UIAxes, ...
                     [minX - 0.05 * deltaX, maxX + 0.05 * deltaX]);
            end

            % Cloning changed values to Y range.
            matchXYRanges = app.SameRangeforXYCheckBox.Value;
            if matchXYRanges
                cloneXRanageIntoYRange(app);
            end

            % Deciding on whether or not to enable generate points button
            % or not.
            assignGenerateButtonProperEnablement(app);
        end

        % Value changed function: YmaxEditField, YminEditField
        function YRangeChanged(app, event)
            % It checks if Y Range fields are valid.
            minY = app.YminEditField.Value;
            maxY = app.YmaxEditField.Value;

            if maxY < minY
                % Invalid y range.
                app.yRangeIsValid = 0;
                app.YminEditField.BackgroundColor = "red";
                app.YmaxEditField.BackgroundColor = "red";

                % Setting error message as tooltips.
                errorMsg = "Ymin should be <= Ymax.";
                app.YminEditField.Tooltip = errorMsg;
                app.YmaxEditField.Tooltip = errorMsg;
            else
                % Valid y range.
                app.yRangeIsValid = 1;
                app.YminEditField.BackgroundColor = "white";
                app.YmaxEditField.BackgroundColor = "white";

                % Resetting tooltips.
                app.YminEditField.Tooltip = "";
                app.YmaxEditField.Tooltip = "";

                deltaY = maxY - minY;
                
                % Dealing with zero delta.
                if deltaY == 0
                    app.yRangeIsZero = 1;
                    deltaY = 1;
                else
                    app.yRangeIsZero = 0;
                end
                
                ylim(app.UIAxes, ...
                     [minY - 0.05 * deltaY, maxY + 0.05 * deltaY]);
            end

            % Deciding on whether or not to enable generate points button
            % or not.
            assignGenerateButtonProperEnablement(app);
        end

        % Button pushed function: GeneratePointsButton
        function GeneratePointsButtonPushed(app, event)
            % Disabling all the left panel to prevent chnages and
            % presses while still working. This is a good practice in 
            % such a case, especially if processing may take noticeable
            % time. We disable all left panel as to avoid changing ranges
            % for instance while generating points. Here as rule of thumb,
            % if several controls might interfere or should not change 
            % while pressing a button or alike, it could be good to have
            % them gathered within one panel or tab to allow whole 
            % enabling / disabling.
            app.LeftPanel.Enable = "off";
            
            numPoints = app.SizeEditField.Value;
            
            minX = app.XminEditField.Value;
            maxX = app.XmaxEditField.Value;

            minY = app.YminEditField.Value;
            maxY = app.YmaxEditField.Value;

            app.pointsWithOrder = zeros(numPoints, 3);
            firstTrial = 1;
            
            % Here we need to assure the generated points are unique. As
            % duplicate points if exist, would be oredered one after
            % another. So, there is no need to include duplicates.
            while firstTrial || ...
                  (size(unique(app.pointsWithOrder(:, 1:2), 'rows'), 1) ~= numPoints)
                % setting first trial to false.
                firstTrial = 0;

                app.pointsWithOrder(:, 1) = minX + (maxX - minX) * rand([numPoints, 1]);
                app.pointsWithOrder(:, 2) = minY + (maxY - minY) * rand([numPoints, 1]);
            end

            % Plotting points and so far ordered path.
            plotPoints(app);
            plotPath(app);

            % Re-enabling left panel. 
            app.LeftPanel.Enable = "on";
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            % Its main target is to decide what options to enable or 
            % disable based on current point and existing ordering.
            index = str2double(event.Source.Parent.CurrentObject.Tag(3:end));
            selectedPointOrder = app.pointsWithOrder(index, end);
            maxOrder = max(app.pointsWithOrder(:, end));

            app.StartWithMenuItem.Enable = "on";
            app.SetAsNextMenuItem.Enable = "on";
            app.ResetOrderMenuItem.Enable = "off";
            app.ResetBeforeMenuItem.Enable = "off";
            app.ResetAfterMenuItem.Enable = "off";

            if selectedPointOrder
                % Point has an order. then we should disable start with
                % option if its order is already one.                
                if selectedPointOrder == 1
                    app.StartWithMenuItem.Enable = "off";                    
                end

                % Cannot set as next. You can achieve the same by 
                % resetting then setting as next.
                app.SetAsNextMenuItem.Enable = "off";

                % Enabling reset order.
                app.ResetOrderMenuItem.Enable = "on";

                % Enabling resetting before if order > 1.
                if selectedPointOrder > 1
                    app.ResetBeforeMenuItem.Enable = "on";
                end

                % Enabling resetting after if order < maxOrder.
                if selectedPointOrder < maxOrder
                    app.ResetAfterMenuItem.Enable = "on";
                end
            else
                % Meaning has no set order yet (value is 0).
                % If no other points have been ordered yet, then only 
                % start with option should be available.
                if ~maxOrder
                    % maxOrder is zero ==> no ordered points yet.
                    % We disable set as next option.
                    app.SetAsNextMenuItem.Enable = "off";
                end
            end
        end

        % Menu selected function: StartWithMenuItem
        function StartWithMenuItemSelected(app, event)
            % It will make the current point as starting point. Those 
            % after it will remain after. If there are points before it,
            % there order will be cycled as to mainatain same virtual 
            % or reeal closed path among ordered points. To make sense of
            % it consider the fully ordered closed path and what you would
            % expect to happen if you chnage the starting point.
            index = str2double(event.Source.Parent.Parent.CurrentObject.Tag(3:end));
            selectedPointOrder = app.pointsWithOrder(index, end);
            
            if selectedPointOrder
                % We need to keep track of those before and after current
                % ordered point for later update.
                numPoints = size(app.pointsWithOrder, 1);
                
                preIndices = find(app.pointsWithOrder(:, end) > 0 & app.pointsWithOrder(:, end) < selectedPointOrder);
                postIndices = find(app.pointsWithOrder(:, end) > selectedPointOrder);

                app.pointsWithOrder(preIndices, end) = app.pointsWithOrder(preIndices, end) + numPoints - selectedPointOrder + 1;
                app.pointsWithOrder(postIndices, end) = app.pointsWithOrder(postIndices, end) - selectedPointOrder + 1;                
            else
                % We need to keep track point of all ordered points as
                % current point has no set order.
                orderedIndices = find(app.pointsWithOrder(:, end) > 0);

                % We increment tracked points indices.
                app.pointsWithOrder(orderedIndices, end) = app.pointsWithOrder(orderedIndices, end) + 1;
            end

            % We set current point order to one.
            app.pointsWithOrder(index, end) = 1;

            % Plotting the so far ordered path.
            plotPath(app);
        end

        % Menu selected function: SetAsNextMenuItem
        function SetAsNextMenuItemSelected(app, event)
            % It will set the order of current point to be after the last
            % ordered point.
            index = str2double(event.Source.Parent.Parent.CurrentObject.Tag(3:end));
            maxOrder = max(app.pointsWithOrder(:, end));
            app.pointsWithOrder(index, end) = maxOrder + 1;   

            % Plotting the so far ordered path.
            plotPath(app);
        end

        % Menu selected function: ResetOrderMenuItem
        function ResetOrderMenuItemSelected(app, event)
            % It unsets the order of current ordered point. The points
            % initially after it will move to become those just after the
            % point before it.
            index = str2double(event.Source.Parent.Parent.CurrentObject.Tag(3:end));
            selectedPointOrder = app.pointsWithOrder(index, end);

            % Unsetting order of point requested.
            app.pointsWithOrder(index, end) = 0;

            % Finding points indices whose order should be changed, 
            % pareticularly those with order > selectedPointOrder.
            indices = find(app.pointsWithOrder(:, end) > selectedPointOrder);
            app.pointsWithOrder(indices, end) = app.pointsWithOrder(indices, end) - 1;

            % Plotting so far ordered points.
            plotPath(app);
        end

        % Menu selected function: ResetAfterMenuItem
        function ResetAfterMenuItemSelected(app, event)
            % It unsets the order of all points after the current point.
            index = str2double(event.Source.Parent.Parent.CurrentObject.Tag(3:end));
            selectedPointOrder = app.pointsWithOrder(index, end);

            % Finding points indices whose order should be reset, 
            % pareticularly those with order > selectedPointOrder.
            indices = find(app.pointsWithOrder(:, end) > selectedPointOrder);
            app.pointsWithOrder(indices, end) = 0;

            % Plotting so far ordered points.
            plotPath(app);
        end

        % Menu selected function: ResetBeforeMenuItem
        function ResetBeforeMenuItemSelected(app, event)
            % It unsets the order of all points before the current point.
            % The current point become the new starting point.
            index = str2double(event.Source.Parent.Parent.CurrentObject.Tag(3:end));
            selectedPointOrder = app.pointsWithOrder(index, end);

            % Finding points indices whose order should be reset, 
            % pareticularly those with order < selectedPointOrder.
            indices = find(app.pointsWithOrder(:, end) < selectedPointOrder);
            app.pointsWithOrder(indices, end) = 0;

            % Updating order points with order >= selectedPointOrder.
            indices = find(app.pointsWithOrder(:, end) >= selectedPointOrder);
            app.pointsWithOrder(indices, end) = app.pointsWithOrder(indices, end) - selectedPointOrder + 1;

            % Plotting so far ordered points.
            plotPath(app);
        end

        % Value changed function: ConnectUponOrderingCheckBox
        function ConnectUponOrderingCheckBoxValueChanged(app, event)
            % Updating the so far ordered path plot.
            plotPath(app);
        end

        % Button pushed function: ResetOrderingButton
        function ResetOrderingButtonPushed(app, event)
            % This will unset order of all points after confirmation as
            % it causes a major change. It is always a good practice to 
            % ask for confirmation when a major change is to be done and 
            % cannot be reverted.
            choice = questdlg("Are you sure you want to reset ordering of all points?", ...
                              "Reset Confirmation", ...
                              "Yes", ...
                              "No", ...
                              "No");

            if choice == "Yes"
                app.pointsWithOrder(:, end) = 0;
                plotPath(app);
            % else: discard the request as nothing to do.
            end
        end

        % Button pushed function: SaveTSPButton
        function SaveTSPButtonPushed(app, event)
            % Opening a save dialog box.
            [fileName, filePath] = uiputfile('*.csv', 'Save TSP to file');

            % Check if the user clicked Cancel (in such a case 0 values
            % are returned as filename and filepath).
            if ~isequal(fileName, 0) && ~isequal(filePath, 0)
                % Constructing the full file path.
                fullFilePath = fullfile(filePath, fileName);

                % Performing save operation.
                writematrix(app.pointsWithOrder, fullFilePath);
            end
        end

        % Value changed function: SizeEditField
        function SizeEditFieldValueChanged(app, event)
            % Triggers verification of x and y ranges with size value in
            % we have zero ranges and size > 1.
            assignGenerateButtonProperEnablement(app);            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TSPManualOrderingAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {505, 505};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TSPManualOrderingAppUIFigure = uifigure('Visible', 'off');
            app.TSPManualOrderingAppUIFigure.Name = 'TSP-Manual-Ordering-App';
            app.TSPManualOrderingAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TSPManualOrderingAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SizeEditFieldLabel = uilabel(app.LeftPanel);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SizeEditField.Limits = [1 100];
            app.SizeEditField.ValueDisplayFormat = '%.0f';
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @SizeEditFieldValueChanged, true);
            app.SizeEditField.Value = 10;
            app.XminEditFieldLabel = uilabel(app.LeftPanel);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @XRangeChanged, true);
            app.XmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XmaxEditField.ValueChangedFcn = createCallbackFcn(app, @XRangeChanged, true);
            app.XmaxEditField.Value = 1;
            app.YminEditFieldLabel = uilabel(app.LeftPanel);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YRangeChanged, true);
            app.YmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YRangeChanged, true);
            app.YmaxEditField.Value = 1;
            app.SameRangeforXYCheckBox = uicheckbox(app.LeftPanel);
            app.SameRangeforXYCheckBox.ValueChangedFcn = createCallbackFcn(app, @SameRangeforXYCheckBoxValueChanged, true);
            app.SameRangeforXYCheckBox.Text = 'Same Range for X & Y';
            app.GeneratePointsButton = uibutton(app.LeftPanel, 'push');
            app.GeneratePointsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePointsButtonPushed, true);
            app.GeneratePointsButton.Text = 'Generate Points';
            app.SaveTSPButton = uibutton(app.LeftPanel, 'push');
            app.SaveTSPButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTSPButtonPushed, true);
            app.SaveTSPButton.Enable = 'off';
            app.SaveTSPButton.Text = 'Save TSP';
            app.ResetOrderingButton = uibutton(app.LeftPanel, 'push');
            app.ResetOrderingButton.ButtonPushedFcn = createCallbackFcn(app, @ResetOrderingButtonPushed, true);
            app.ResetOrderingButton.Enable = 'off';
            app.ResetOrderingButton.Text = 'Reset Ordering...';
            app.ConnectUponOrderingCheckBox = uicheckbox(app.LeftPanel);
            app.ConnectUponOrderingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConnectUponOrderingCheckBoxValueChanged, true);
            app.ConnectUponOrderingCheckBox.Text = 'Connect Upon Ordering';
            app.ConnectUponOrderingCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'TSP')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-0.05 1.05];
            app.UIAxes.YLim = [-0.05 1.05];
            app.ContextMenu = uicontextmenu(app.TSPManualOrderingAppUIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.StartWithMenuItem = uimenu(app.ContextMenu);
            app.StartWithMenuItem.MenuSelectedFcn = createCallbackFcn(app, @StartWithMenuItemSelected, true);
            app.StartWithMenuItem.Text = 'Start With';
            app.StartWithMenuItem.Tag = 'StartWith';
            app.SetAsNextMenuItem = uimenu(app.ContextMenu);
            app.SetAsNextMenuItem.MenuSelectedFcn = createCallbackFcn(app, @SetAsNextMenuItemSelected, true);
            app.SetAsNextMenuItem.Text = 'Set As Next Visit';
            app.SetAsNextMenuItem.Tag = 'SetAsNext';
            app.ResetBeforeMenuItem = uimenu(app.ContextMenu);
            app.ResetBeforeMenuItem.MenuSelectedFcn = createCallbackFcn(app, @ResetBeforeMenuItemSelected, true);
            app.ResetBeforeMenuItem.Text = 'Reset Points Before';
            app.ResetBeforeMenuItem.Tag = 'ResetBefore';
            app.ResetAfterMenuItem = uimenu(app.ContextMenu);
            app.ResetAfterMenuItem.MenuSelectedFcn = createCallbackFcn(app, @ResetAfterMenuItemSelected, true);
            app.ResetAfterMenuItem.Text = 'Reset Points After ';
            app.ResetAfterMenuItem.Tag = 'ResetAfter';
            app.ResetOrderMenuItem = uimenu(app.ContextMenu);
            app.ResetOrderMenuItem.MenuSelectedFcn = createCallbackFcn(app, @ResetOrderMenuItemSelected, true);
            app.ResetOrderMenuItem.Text = ' Reset Order';
            app.ResetOrderMenuItem.Tag = 'ResetOrder';
            % Assign app.ContextMenu
            app.RightPanel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.TSPManualOrderingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TSP_Manual_Solver_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSPManualOrderingAppUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TSPManualOrderingAppUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSPManualOrderingAppUIFigure)
        end
    end
end